classdef Carriers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataFittingPanel                matlab.ui.container.Panel
        TRPLFittedresultsMATfileLabel   matlab.ui.control.Label
        TRPLMATfilePath                 matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        UserShowrawdata                 matlab.ui.control.CheckBox
        OutputfilesuffixLabel           matlab.ui.control.Label
        UserOutputFileSuffix            matlab.ui.control.EditField
        ShowResultsCheckBox             matlab.ui.control.CheckBox
        SimulateButton                  matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        PLFittedresultsMATfileEditFieldLabel  matlab.ui.control.Label
        PLMATfilePath                   matlab.ui.control.EditField
        NoPLDataCheckBox                matlab.ui.control.CheckBox
        AbortButton                     matlab.ui.control.Button
        DataFittingStatusEditFieldLabel  matlab.ui.control.Label
        ToolStatus                      matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        ErrorEditFieldLabel             matlab.ui.control.Label
        ErrorReport                     matlab.ui.control.EditField
        ParametersPanel                 matlab.ui.container.Panel
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        SuperLatticeCheckBox            matlab.ui.control.CheckBox
        DopingDensityEditFieldLabel     matlab.ui.control.Label
        DopingDensityEditField          matlab.ui.control.NumericEditField
        X1e15cm3Label                   matlab.ui.control.Label
        m1Label                         matlab.ui.control.Label
        meVLabel                        matlab.ui.control.Label
        DefectLevelEditFieldLabel       matlab.ui.control.Label
        DefectLevelEditField            matlab.ui.control.NumericEditField
        DefectDensityEditFieldLabel     matlab.ui.control.Label
        DefectDensityEditField          matlab.ui.control.NumericEditField
        DopingTypeDropDownLabel         matlab.ui.control.Label
        DopingDropDown                  matlab.ui.control.DropDown
        ActiveRegionThicknessEditFieldLabel  matlab.ui.control.Label
        ActiveRegionThicknessEditField  matlab.ui.control.NumericEditField
        umLabel                         matlab.ui.control.Label
        CaplayerrefractiveindexatbandgapenergyEditFieldLabel  matlab.ui.control.Label
        CaplayerrefractiveindexatbandgapenergyEditField  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Initialize(app, event)
            
            % Reset the error report and background return
            app.ErrorReport.Value = '';
            
            % Empty vectors from workspace that could confuse the tool if subsequent load steps fail
            Carrier_WorkspaceReset
            % Global variables for Previous/Repeat/Advance/Abort button presses
            global simulateData
            global fitData
            global saveResults
            global abortNow
            
            Carrier_Starting
            % Get the user specified MAT File
            DataToAnalyze1 = app.TRPLMATfilePath.Value;
            DataToAnalyze2 = app.PLMATfilePath.Value;
            
           
            noPLData = app.NoPLDataCheckBox.Value;
            % MAT File should contain the following
            %   Time_Axis:         Time axis of acquisition, column vector in units of us
            %   Temperatures:      Temperatures for each acquisition, row vector in units of K
            %   Excitations:       Excitations for each acquisition, col vector, units of deg (TRPL) or V (TMR)
            %   AdaptiveParameter: Adaptable parameter that can be swept through
            %   Adaptives:         Values of the adaptable parameter for each acquisition, col vector   
            %   RawData:           Vector containing raw data, size = (Time_Axis,Temperatures,Excitations,Adaptives)
            %   SampleName:        Sample name and identifying information
            %   TRPL_or_TMR:       String specifying if loaded data is TRPL or TMR acquisition
            %   Decay:             Values of the decay data with the baseline subtracted
            %   Decay_WithinFitWindow: Fit window chosen for the decay data
            %   t0_vTemp:          lifetime calculated to extract the data 
            
            try
                load( DataToAnalyze1 , 'Time_Axis' , 'Temperatures' , 'Excitations' ,...
                    'SampleName' , 'TRPL_or_TMR' , 'AdaptiveParameter' ,...
                    'Adaptives' , 'AdaptiveUnits',  't0_vTemp');
            catch
                ErrorMessage = 'Problem trying to load TRPL MAT file at specified path';
                app.ErrorReport.Value = ErrorMessage;
                error(ErrorMessage)
            end


            % Create a color wheel to overlay temperature-dependent data, different colors with each excitation level
            ColorWheel = [ 'bo-' ; 'go-' ; 'ro-' ; 'ko-' ; 'co-' ; 'yo-' ];
            
            % Create a color wheel to overlay temperature-dependent data, different styles with each
            % recombination component
            ColorWheel2 = [ "k--" ; "k-." ; "k:" ; "k-"];
  
            
            adaptive_Ind = 1;
            
            %Plot as a function of excitations
            for exciteInd = 1:length(Excitations)
                plot(app.UIAxes,Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd), ColorWheel(exciteInd,:),...
                    'Displayname', string(Excitations(exciteInd)))
                hold(app.UIAxes,'on')
            end
            
            hold(app.UIAxes,'off')
            
            
            % Initialize parameters that will be used in the simulation
            % The database from the CSV will be read and temperature probes
            % are initialized here too
            materialsDataBase = readtable('BandParameters.csv');
            
            % have a temperature simulation from 50-330 K in steps of 1 K
            beginTemp = 50;
            endTemp = 330;
            stepNumber = endTemp - beginTemp +1;
            Tprobe = linspace(beginTemp,endTemp,stepNumber);
            tauAug = [];
            tauRad = [];
            tauSRH = [];
            totalLifetime = [];


            simulateData = 0;
            fitData = 0;
            saveResults = 0;
            abortNow = 0;
           
           
            % Reset WaitingForUser to 1 to hold in while-loop
            waitingForUser = 1;
                               
            % WhileBreaker variable increments to time out the while-loop
            whileBreaker = 0;
                                
            % Define while-loop time increment
            whileIterationTime = 0.05; % 50 ms iterations
                                
            % Define while-loop total time limit to break loop
            whileTimeLimit = 360; % 360 seconds = 5 minutes
            whileIterationLimit = whileTimeLimit/whileIterationTime;
            
            % Operations started, set status to active
            Carrier_Activate;
            while(waitingForUser)
                
              % Wait the while-loop time incre
                pause(whileIterationTime) 
                                    
              % Increment WhileBreaker
                whileBreaker = whileBreaker + 1;


              % Simulate the data from the parameters box
                if(simulateData)
                    material = '';
                    bandParams = [];
                    materialDopingType = '';
                    dopingDensity = nan;
                    defectDensity = nan;
                    defectLevel  = nan;
                    DataToAnalyze2 = '';
                    Nc  = nan;
                    Nv  = nan;
                    ni  = nan;
                    G   = nan;
                    phi = nan;


                 %   materialsDataBase = readtable('BandParameters.csv');  
                    material = app.MaterialDropDown.Value;
                    bandParams = nan*ones(9,1);
                   
           
                    switch(material) 
                     case 'InAs'
                        bandParams =materialsDataBase.InAs;
       
                     case 'GaAs'
                        bandParams = materialsDataBase.GaAs;
                     case 'InSb'
                        bandParams = materialsDataBase.InSb;
                     case 'InAsSb'
                         % use InAs band parameters
                        bandParams = materialsDataBase.InAs;
                     otherwise
                        bandParams = materials.DataBase.InAs;
                
                    end
                    % Band Parameters 
                    %row1     =  Band gap in eV (0 K) perhaps
                    %row2     =  electron effective mass in units of free electron mass
                    %row3     =  gamma1 inverse effective mass parameter 1
                    %row4     =  gamma2 inverse effective mass parameter 2
                    %row5     =  gamma3 inverse effective mass parameter 3
                    %row6     =  alpha 0 K band gap using Varshni equation
                    %row7     =  beta  slope of the Varshni equation
                    %row8     =  einf static dielectric constant
                    %row9     =  F1F2  Auger overlap integral parameter
            
                    type = 'i';
                    materialDopingType = app.DopingDropDown.Value;
                      % Check the doping Type
                    switch(materialDopingType)
                     case('Intrinsic')
                        type = 'i';
                     case('p-type')
                        type ='p';
                     case('n-type')
                        type = 'n';
                    
                    end              
                    dopingDensity = app.DopingDensityEditField.Value;
                    defectDensity = app.DefectDensityEditField.Value;
            
                    % Units of meV, so change to eV The negative shows that
                    % the conduction band edge is the zero energy level
                    defectLevel = -app.DefectLevelEditField.Value/1000;
                    
                    
                    %Load PL fitted data and use the bose einstein parameters for
                    %bandgap as a function of temperature
                    DataToAnalyze2 = app.PLMATfilePath.Value;
                    if(~noPLData)
                        try
                            load(DataToAnalyze2, 'boseParameters')
                   
                        catch 
                            ErrorMessage = 'Problem trying to load PL MAT file: does file exist and contain "boseParameters?"';
                            app.ErrorReport.Value = ErrorMessage;
                            error(ErrorMessage);
                    
                        end
                    end


                    % extract the band parameters and use the Varshni equation
                    % with given parameters from the Vurgaftman review article
                    [meStar, mhStar, eg, nRefractive,...
                kExtinction, photonEnergyum, varshniEinstein, einf, f1f2] = extractParameters(bandParams, material);
            
                
                    % If there is PL data, we will use the Bose Einstein single
                    % oscillator equation 
                    % The exponential modified gaussian fit method will be used, which is
                    % close to the first derivative method of extracting the band
                    % gap
                    noPLData = app.NoPLDataCheckBox.Value;
                    if(~noPLData)
                        varshniEinstein = @(T) boseEinstein(T, boseParameters(1,1), boseParameters(2,1), ...
                        boseParameters(3,1));
                        eg = boseParameters(1,1);
                    end


                    % Band gap at 300 K in eV
                    eg300 = varshniEinstein(300) / 1000;
            
                    % Temperature dependent band gap in units of eV
                    egTemp = varshniEinstein(Tprobe) ./ 1000;
           
                    % Conduction band edge energy will be equal to the band gap at
                    % 0K
                    conductionEdge = 0;
            
                    % Set the valence band edge having the bose einstein temperature
                    % dependence
                    valenceEdge = conductionEdge - egTemp;
                
                    %get the active region thickness and convert it to nm
                    t = app.ActiveRegionThicknessEditField.Value * 1000;
                    n = app.CaplayerrefractiveindexatbandgapenergyEditField.Value;
                    
                    [Nc, Nv, ni, G, phi] = ...
                        calculateKeyParameters(Tprobe, meStar, mhStar, photonEnergyum,...
                        nRefractive,kExtinction, eg300, egTemp, 1200, n, t);
                    
                    
                    % calculate the fitted lifetimes
                    [totalLifetime, tauRad, tauSRH, tauAug] = calculateLifetimes(Tprobe, type,meStar,...
                    mhStar, egTemp, valenceEdge,conductionEdge,f1f2, einf,...
                    Nc, Nv, ni, G, phi, dopingDensity, defectLevel, defectDensity);
            
                    %should we plot in a new Figure?
                    plotresults = app.ShowResultsCheckBox.Value;

                    if(plotresults)
                        
                        
                        figure
                        hold on
                        for exciteInd = 1:length(Excitations)
                            plot(Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd), ColorWheel(exciteInd,:),...
                                'Displayname', string(Excitations(exciteInd)))
                        end
                        plot(Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                        plot(Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                        plot(Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                        plot(Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                        legend()
                        set(gca,'YScale', 'log')
                        hold off
                        ylim([1e-1, 1e3])
                        
                    else
                
                        % Plot as a function of excitations
                        for exciteInd = 1:length(Excitations)
                            plot(app.UIAxes,Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd), ColorWheel(exciteInd,:),...
                                'Displayname', string(Excitations(exciteInd)))
                            hold(app.UIAxes,'on')
                        end
                    
                        
                        plot(app.UIAxes,Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                        plot(app.UIAxes,Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                        plot(app.UIAxes,Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                        plot(app.UIAxes,Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                        legend(app.UIAxes)
                        set(app.UIAxes, 'YScale', 'log')
                        hold(app.UIAxes, 'off')
                        ylim(app.UIAxes,[1e-1, 1e3])
                        
                    end


                    simulateData = 0;
                    %Restart while Breaker
                    whileBreaker = 0;
                %{ 
                Plot the recombination combonents on a  new figure
                (For debuggin purposes)
            figure()
            semilogy(Tprobe,tauRad,'--')
            hold on
            plot(Tprobe,tauSRH,':')
            plot(Tprobe, tauAug,'-.')
            plot(Tprobe, totalLifetime,'-')
            ylim([1e-8, 1e-4])
            %}            
            
            %{
                plot each lifetime component in their own figure then
                create a new figure of all the components added together
            figure()
            tauRad = radiativeLifetime(Tprobe,type,meStar, mhStar, egTemp,eg300,nRefractive, kExtinction,photonEnergyum,dopingDensity);
            semilogy(Tprobe,tauRad)
            xlim([50,330])
            ylim([1e-9, 1e-4])
            title('Radiative Lifetime')
            
            figure()
            tauSRH = 1e6.*shockleyReadHallLifetime(Tprobe, type, meStar, mhStar,valenceEdge,...
                conductionEdge, defectLevel, defectDensity,dopingDensity);
            semilogy(Tprobe,tauSRH)
            xlim([50,330])
            ylim([1e-9, 1e-3])
            title('SRH Lifetime')
                
            figure()
            tauAug = 1e6.*augerLifetime(Tprobe, type, meStar, mhStar, f1f2,egTemp, einf,dopingDensity);
            semilogy(Tprobe, tauAug)
            xlim([50,330])
            ylim([1e-9,1e-3])
            title('Auger Lifetime')


            totalLifetime = 1e-6./(1./tauRad + 1./tauAug + 1./tauSRH);
            
            
            figure()
            
            semilogy(Tprobe,tauRad,'-')
            hold on
            plot(Tprobe,tauSRH,'.')
            plot(Tprobe, tauAug,'--')
            plot(Tprobe, totalLifetime,'-')
            ylim([1e-8, 1e-4])
                    %}
                    
                    %It will essentially do the same thing but first fit
                    %and then plot
                elseif(fitData)
                    % re initialize the data, because user may go straight
                    % to fitting routine
                    material = '';
                    bandParams = [];
                    materialDopingType = '';
                    dopingDensity = nan;
                    defectDensity = nan;
                    defectLevel  = nan;
                    DataToAnalyze2 = '';
                    Nc  = nan;
                    Nv  = nan;
                    ni  = nan;
                    G   = nan;
                    phi = nan;
                  %  materialsDataBase = readtable('BandParameters.csv');  
                    material = app.MaterialDropDown.Value;
                    
                    %row1     =  Band gap in eV (0 K) perhaps
                    %row2     =  electron effective mass in units of free electron mass
                    %row3     =  gamma1 inverse effective mass parameter 1
                    %row4     =  gamma2 inverse effective mass parameter 2
                    %row5     =  gamma3 inverse effective mass parameter 3
           
                    switch(material) 
                     case 'InAs'
                        bandParams =materialsDataBase.InAs;
       
                     case 'GaAs'
                        bandParams = materialsDataBase.GaAs;
                     case 'InSb'
                        bandParams = materialsDataBase.InSb;
                     case 'InAsSb'
                         % use InAs band parameters
                        bandParams = materialsDataBase.InAs;
                     otherwise
                        bandParams = materials.DataBase.InAs;
                
                    end
            
                    type = 'i';
                    materialDopingType = app.DopingDropDown.Value;
                      % Check the doping Type
                    switch(materialDopingType)
                     case('Intrinsic')
                        type = 'i';
                     case('p-type')
                        type ='p';
                     case('n-type')
                        type = 'n';
                    
                    end              
                    % in units of 1e15 per cm^3 we will convert to per cm^3
                    % in the functions (fitting a very large number seems
                    % to cause it to be insensitive)
                    dopingDensity = app.DopingDensityEditField.Value;
            
                    % in units of per m
                    defectDensity = app.DefectDensityEditField.Value;
            
                    % Units of meV, so change to eV The negative shows that
                    % the conduction band edge is the zero energy level
                    defectLevel = -app.DefectLevelEditField.Value/1000;


                    % extract the band parameters and use the Varshni equation
                    % with given parameters from the Vurgaftman review article
                    [meStar, mhStar, eg, nRefractive,...
                kExtinction, photonEnergyum, varshniEinstein, einf, f1f2] = extractParameters(bandParams, material);
            
                
                    % If there is PL data, we will use the Bose Einstein single
                    % oscillator equation 
                    % The exponential modified gaussian fit method will be used, which is
                    % close in agreement to the first derivative method of extracting the band
                    % gap----SEE the PL_Analyzer app in PL_AnalyzerCurveFittingToolboxV2
                    noPLData = app.NoPLDataCheckBox.Value;
                    if(~noPLData)
                        varshniEinstein = @(T) boseEinstein(T, boseParameters(1,1), boseParameters(2,1), ...
                        boseParameters(3,1));
                        eg = boseParameters(1,1);
                    end


                    % Band gap at 300 K in eV
                    eg300 = varshniEinstein(300) / 1000;
            
                    % Temperature dependent band gap in units of eV
                    egTemp = varshniEinstein(Tprobe) ./ 1000;
                    egTemp2 = varshniEinstein(Temperatures)./ 1000;
           
                    % Conduction band edge energy will be equal to the band gap at
                    % 0K
                    conductionEdge = 0;
            
                    % Set the valence band edge having the bose einstein temperature
                    % dependence
                    valenceEdge =  conductionEdge - egTemp;
                    valenceEdge2 = conductionEdge - egTemp2;
                
                    %get the active region thickness and convert it to nm
                    t = app.ActiveRegionThicknessEditField.Value * 1000;
                    n = app.CaplayerrefractiveindexatbandgapenergyEditField.Value;
                    
                    %The first excitation will be used to fit the lifetime
                    % 
                    y = t0_vTemp(adaptive_Ind,:,1);
                    
                     [Nc2, Nv2, ni2, G2, phi] = ...
                        calculateKeyParameters(Temperatures, meStar, mhStar, photonEnergyum,...
                        nRefractive,kExtinction, eg300, egTemp2, 1200, n, t);

                    
                    [fobject, gof, output] = fitLifetimes(Temperatures, y,type,meStar,...
                    mhStar, egTemp2, valenceEdge2, conductionEdge, f1f2, einf,...
                    Nc2, Nv2, ni2, G2, phi,  dopingDensity, defectLevel, defectDensity);
                
                    dopingDensity = fobject.p;
                    app.DopingDensityEditField.Value = dopingDensity;
                    
                    defectLevel = fobject.q;
                    app.DefectLevelEditField.Value = -defectLevel * 1000;
                    
                    defectDensity = fobject.r;
                    app.DefectDensityEditField.Value = defectDensity;
                    
                     [Nc, Nv, ni, G, phi] = ...
                        calculateKeyParameters(Tprobe, meStar, mhStar, photonEnergyum,...
                        nRefractive,kExtinction, eg300, egTemp, 1200, n, t);
                    
                    % calculate the lifetimes
                    [totalLifetime, tauRad, tauSRH, tauAug] = calculateLifetimes(Tprobe, type,meStar,...
                    mhStar, egTemp, valenceEdge, conductionEdge, f1f2, einf,Nc, Nv, ni, G, phi,...
                    dopingDensity, defectLevel, defectDensity);
            
                
                    % Plot as a function of excitations
                    for exciteInd = 1:length(Excitations)
                        plot(app.UIAxes,Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd), ColorWheel(exciteInd,:),...
                             'Displayname', string(Excitations(exciteInd)))
                        hold(app.UIAxes,'on')
                    end
                    plot(app.UIAxes,Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                    plot(app.UIAxes,Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                    plot(app.UIAxes,Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                    plot(app.UIAxes,Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                    legend(app.UIAxes)
                    set(app.UIAxes, 'YScale', 'log')
                    
                    hold(app.UIAxes, 'off')
                    ylim(app.UIAxes,[1e-1, 1e3])


                    %Reset the fitting routine
                    fitData = 0;
                    
                    %Reset the wait time to exit/abort
                    whileBreaker = 0;
                    
                    
                elseif(saveResults)
                    saveTable = table();
                    try
                        saveTable = table(Tprobe',tauRad,tauSRH,tauAug,totalLifetime);
                    catch 
                        errorMessage = 'Problem Creating a table to save lifetime components. Try to simulate data';
                        app.ErrorReport.Value = errorMessage;
                        saveResults = 0;
                        continue;
                        
                    end
                    LT_Writing;
                    
                    
                    saveTable.Properties.VariableNames = {'Temp_K', 'tauRad_us', 'tauSRH_us', 'tauAug_us','Total_us'};
                    filename = string(SampleName) + "_LifetimeComponents.txt";
                    writetable(saveTable, filename);
                    
                    LT_Activate;
                    
                    
                    saveTable2 = table(dopingDensity,app.DefectLevelEditField.Value, defectDensity);
                    saveTable2.Properties.VariableNames = {'DopingDensity_1e15_cm-3', 'DefectLevel_meV', 'defectDensity_m-1'};
                    filename2 = string(SampleName) + '_InputParameters.txt';
                    writetale(saveTable2,filename2);
                    
                    
                    %Reset the save routine and leave app in wait mode
                    saveResults = 0;


                    whileBreaker = 0;
                    
            
                elseif(abortNow || (whileBreaker > whileIterationLimit))
                    % Operations aborted, reset tool
                      LT_HardReset
                                      
                    % Reset clears the decay plot, also clear the temperature dependence plot
                      scatter( app.UIAxes , 1 , 1 , 'x' ) 
                      abortNow = 0;
                    % Abort if user presses button or if whileIterationLimit time-out reached                                
                      return


                end
               
             end


        end

        % Button pushed function: AbortButton
        function AbortCalculation(app, event)
            
            % Define global variable AbortNow = 1 to enable analysis to repeat
            global abortNow
            abortNow = 1;
                  
            
        end

        % Button pushed function: SimulateButton
        function simulateLifetimes(app, event)
              
          global simulateData
          simulateData = 1;
        
        end

        % Button pushed function: FitButton
        function fitLifetimes(app, event)
            global fitData
            fitData = 1;
        end

        % Button pushed function: SaveButton
        function saveModel(app, event)
            global saveResults
            saveResults = 1;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataFittingPanel = uipanel(app.UIFigure);
            app.DataFittingPanel.Title = 'Data Fitting';
            app.TRPLFittedresultsMATfileLabel = uilabel(app.DataFittingPanel);
            app.TRPLFittedresultsMATfileLabel.Text = 'TRPL Fitted results MAT file:';
            app.TRPLMATfilePath = uieditfield(app.DataFittingPanel, 'text');
            app.TRPLMATfilePath.Value = 'L19-091_FittedResults.mat';
            app.StartButton = uibutton(app.DataFittingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.StartButton.Text = 'Start';
            app.UserShowrawdata = uicheckbox(app.DataFittingPanel);
            app.UserShowrawdata.Text = 'Show raw data?';
            app.OutputfilesuffixLabel = uilabel(app.DataFittingPanel);
            app.OutputfilesuffixLabel.Text = 'Output file suffix:';
            app.UserOutputFileSuffix = uieditfield(app.DataFittingPanel, 'text');
            app.ShowResultsCheckBox = uicheckbox(app.DataFittingPanel);
            app.ShowResultsCheckBox.Text = 'Show Results?';
            app.SimulateButton = uibutton(app.DataFittingPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateLifetimes, true);
            app.SimulateButton.Text = 'Simulate';
            app.FitButton = uibutton(app.DataFittingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @fitLifetimes, true);
            app.FitButton.Text = 'Fit';
            app.SaveButton = uibutton(app.DataFittingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveModel, true);
            app.SaveButton.Text = 'Save';
            app.PLFittedresultsMATfileEditFieldLabel = uilabel(app.DataFittingPanel);
            app.PLFittedresultsMATfileEditFieldLabel.Text = 'PL Fitted results MAT file:';
            app.PLMATfilePath = uieditfield(app.DataFittingPanel, 'text');
            app.PLMATfilePath.Value = 'L19-091_FittedResultsPL.mat';
            app.NoPLDataCheckBox = uicheckbox(app.DataFittingPanel);
            app.NoPLDataCheckBox.Text = 'No PL Data?';
            app.AbortButton = uibutton(app.DataFittingPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortCalculation, true);
            app.AbortButton.Text = 'Abort';
            app.DataFittingStatusEditFieldLabel = uilabel(app.UIFigure);
            app.DataFittingStatusEditFieldLabel.Text = 'Data Fitting Status:';
            app.ToolStatus = uieditfield(app.UIFigure, 'text');
            app.ToolStatus.Editable = 'off';
            app.ToolStatus.Value = 'INACTIVE';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Dependence')
            xlabel(app.UIAxes, 'Temperature (K)')
            ylabel(app.UIAxes, 'Characteristic Slope (us)')
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorReport = uieditfield(app.UIFigure, 'text');
            app.ErrorReport.Editable = 'off';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MaterialDropDownLabel = uilabel(app.ParametersPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.ParametersPanel);
            app.MaterialDropDown.Items = {'InAs', 'GaAs', 'InAsSb', 'InSb'};
            app.MaterialDropDown.Value = 'InAs';
            app.SuperLatticeCheckBox = uicheckbox(app.ParametersPanel);
            app.SuperLatticeCheckBox.Text = 'SuperLattice?';
            app.DopingDensityEditFieldLabel = uilabel(app.ParametersPanel);
            app.DopingDensityEditFieldLabel.Text = 'Doping Density';
            app.DopingDensityEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DopingDensityEditField.Value = 1.7;
            app.X1e15cm3Label = uilabel(app.ParametersPanel);
            app.X1e15cm3Label.Text = 'X1e15 cm(-3)';
            app.m1Label = uilabel(app.ParametersPanel);
            app.m1Label.Text = 'm(-1)';
            app.meVLabel = uilabel(app.ParametersPanel);
            app.meVLabel.Text = 'meV';
            app.DefectLevelEditFieldLabel = uilabel(app.ParametersPanel);
            app.DefectLevelEditFieldLabel.Text = 'Defect Level';
            app.DefectLevelEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DefectLevelEditField.Value = 67;
            app.DefectDensityEditFieldLabel = uilabel(app.ParametersPanel);
            app.DefectDensityEditFieldLabel.Text = 'Defect Density';
            app.DefectDensityEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DefectDensityEditField.Value = 0.7;
            app.DopingTypeDropDownLabel = uilabel(app.ParametersPanel);
            app.DopingTypeDropDownLabel.Text = 'Doping Type';
            app.DopingDropDown = uidropdown(app.ParametersPanel);
            app.DopingDropDown.Items = {'Intrinsic', 'n-type', 'p-type', 'Option 4'};
            app.DopingDropDown.Value = 'n-type';
            app.ActiveRegionThicknessEditFieldLabel = uilabel(app.ParametersPanel);
            app.ActiveRegionThicknessEditFieldLabel.Text = 'Active Region Thickness';
            app.ActiveRegionThicknessEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ActiveRegionThicknessEditField.Value = 2;
            app.umLabel = uilabel(app.ParametersPanel);
            app.umLabel.Text = '(um)';
            app.CaplayerrefractiveindexatbandgapenergyEditFieldLabel = uilabel(app.ParametersPanel);
            app.CaplayerrefractiveindexatbandgapenergyEditFieldLabel.Text = 'Cap layer refractive index at bandgap energy';
            app.CaplayerrefractiveindexatbandgapenergyEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CaplayerrefractiveindexatbandgapenergyEditField.Value = 3.7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Carriers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end